classdef LAB_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DivergencePanel            matlab.ui.container.Panel
        EditField_5                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.EditField
        EditField_3                matlab.ui.control.EditField
        DivergenceButton           matlab.ui.control.Button
        CurlPanel                  matlab.ui.container.Panel
        EditField3                 matlab.ui.control.EditField
        EditField2                 matlab.ui.control.EditField
        CurlButton                 matlab.ui.control.Button
        K_componentEditField       matlab.ui.control.EditField
        K_componentEditFieldLabel  matlab.ui.control.Label
        J_componentEditField       matlab.ui.control.EditField
        J_componentEditFieldLabel  matlab.ui.control.Label
        I_componentEditField       matlab.ui.control.EditField
        I_componentEditFieldLabel  matlab.ui.control.Label
        Slider2                    matlab.ui.control.Slider
        Slider2Label               matlab.ui.control.Label
        AtFirstTakeAllInputValueAndFollowMatrixMultiplicationRulesLabel  matlab.ui.control.Label
        ValueofFunctionatxyzPanel  matlab.ui.container.Panel
        ResultEditField            matlab.ui.control.NumericEditField
        ResultEditFieldLabel       matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        GradientPanel              matlab.ui.container.Panel
        FunctionEditField          matlab.ui.control.EditField
        EditField                  matlab.ui.control.EditField
        FunctionEditFieldLabel     matlab.ui.control.Label
        EditField_2                matlab.ui.control.EditField
        GradientButton             matlab.ui.control.Button
        Slider3                    matlab.ui.control.Slider
        Slider3Label               matlab.ui.control.Label
        Slider1                    matlab.ui.control.Slider
        Slider1Label               matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
    end

methods (Access = private)
        
       function solvegrad(app, x1, y1, z1)
    syms x y z real;

    i = sym('i');
    j = sym('j');
    k = sym('k');
    v = [x, y, z];
    k1919 = app.FunctionEditField.Value;
    f = str2func(['@(x,y,z)' k1919]);
    g = [diff(f, x); diff(f, y); diff(f, z)];

   
    l1 = matlabFunction(g(1), 'Vars', [x, y, z]);
    l2 = matlabFunction(g(2), 'Vars', [x, y, z]);
    l3 = matlabFunction(g(3), 'Vars', [x, y, z]);

   
    delta = 0.001;  
    k111 = (feval(f, x1 + delta, y1, z1) - feval(f, x1 - delta, y1, z1)) / (2 * delta);
    k222 = (feval(f, x1, y1 + delta, z1) - feval(f, x1, y1 - delta, z1)) / (2 * delta);
    k333 = (feval(f, x1, y1, z1 + delta) - feval(f, x1, y1, z1 - delta)) / (2 * delta);

  
    l44 = func2str(l1);
    indx = strfind(l44, ')');
    l4 = [l44(indx+1:end )];

    l55 = func2str(l2);
    indx = strfind(l55, ')');
    l5 = [l55(indx+1:end )];

    l66 = func2str(l3);
    indx = strfind(l66, ')');
    l6 = [l66(indx+1:end )];

    l11 = append('(', l4, ')*i + (', l5, ')*j + (', l6, ')*k');
    app.EditField.Value = l11;

   
             w1=num2str(k111);
             w2=num2str(k222);
             w3=num2str(k333);
             w4=append(w1,"i")
             w5=append(w2,"j")
             w6=append(w3,"k")

    
    if k222 >= 0 && k333 >= 0
        w7 = append(w4, '+', w5, '+', w6);
    elseif k222 >= 0 && k333 < 0
        w6 = append('(', w6, ')');
        w7 = append(w4, '+', w5, '+', w6);
    elseif k222 < 0 && k333 >= 0
        w5 = append('(', w5, ')');
        w7 = append(w4, '+', w5, '+', w6);
    else
        w6 = append('(', w6, ')');
        w5 = append('(', w5, ')');
        w7 = append(w4, '+', w5, '+', w6);
    end

    app.EditField_2.Value = w7;
end


        function graphgrad(app)
    syms x y z real;
    i = sym('i');
    j = sym('j');
    k = sym('k');
    v = [x, y, z];
    k1919 = app.FunctionEditField.Value;
    f = str2func(['@(x,y,z)', k1919]);
    g = [diff(f, x); diff(f, y); diff(f, z)];

    % Numerical calculation of the gradient
    delta = 0.1;
    [X, Y, Z] = meshgrid(-1:delta:1, -1:delta:1, -1:delta:1);
    G_numerical = zeros(size(X, 1), size(X, 2), size(X, 3), 3);

    for i = 1:size(X, 1)
        for j = 1:size(X, 2)
            for k = 1:size(X, 3)
                G_numerical(i, j, k, 1) = (feval(f, X(i, j, k) + delta, Y(i, j, k), Z(i, j, k)) - feval(f, X(i, j, k), Y(i, j, k), Z(i, j, k))) / delta;
                G_numerical(i, j, k, 2) = (feval(f, X(i, j, k), Y(i, j, k) + delta, Z(i, j, k)) - feval(f, X(i, j, k), Y(i, j, k), Z(i, j, k))) / delta;
                G_numerical(i, j, k, 3) = (feval(f, X(i, j, k), Y(i, j, k), Z(i, j, k) + delta) - feval(f, X(i, j, k), Y(i, j, k), Z(i, j, k))) / delta;
            end
        end
    end

    % Quiver plot for numerical calculation
    figure;
    ax1 = gca;
    quiver3(ax1, X, Y, Z, G_numerical(:,:,:,1), G_numerical(:,:,:,2), G_numerical(:,:,:,3));
    title('Numerically Calculated Gradient');

    % Symbolic calculation of the gradient
    [X, Y, Z] = meshgrid(-1:0.1:1, -1:0.1:1, -1:0.1:1);
    G1 = subs(g(1), v, {X, Y, Z});
    G2 = subs(g(2), v, {X, Y, Z});
    G3 = subs(g(3), v, {X, Y, Z});

    % Quiver plot for symbolic calculation
    figure;
    ax2 = gca;
    quiver3(ax2, X, Y, Z, G1, G2, G3);
    title('Symbolically Calculated Gradient');
end

       function graphcurl(app)
    syms x y z real;
    i = sym('i');
    j = sym('j');
    k = sym('k');
    v = [x, y, z];
    
    % Extracting the components of the vector field
    p1 = app.I_componentEditField.Value;
    p2 = app.J_componentEditField.Value;
    p3 = app.K_componentEditField.Value;
    
    % Creating symbolic vector field
    f1 = str2func(['@(x,y,z)' p1]);
    f2 = str2func(['@(x,y,z)' p2]);
    f3 = str2func(['@(x,y,z)' p3]);
    f4 = diff(f1, y);
    f5 = diff(f1, z);
    f6 = diff(f2, x);
    f7 = diff(f2, z);
    f8 = diff(f3, x);
    f9 = diff(f3, y);
    g = [f9 - f7; f5 - f8; f6 - f4];
    
    % Numerical calculation of the curl
    delta = 0.1;
    [X, Y, Z] = meshgrid(-1:delta:1, -1:delta:1, -1:delta:1);
    
    G_numerical = zeros(size(X, 1), size(X, 2), size(X, 3), 3);
    
    for i = 1:size(X, 1)
        for j = 1:size(X, 2)
            for k = 1:size(X, 3)
                % Numerical calculation of the components of the curl
                G_numerical(i, j, k, 1) = (feval(f3, X(i, j, k), Y(i, j, k) + delta, Z(i, j, k)) - feval(f3, X(i, j, k), Y(i, j, k) - delta, Z(i, j, k))) / (2 * delta) - (feval(f2, X(i, j, k), Y(i, j, k), Z(i, j, k) + delta) - feval(f2, X(i, j, k), Y(i, j, k), Z(i, j, k) - delta)) / (2 * delta);
                G_numerical(i, j, k, 2) = (feval(f1, X(i, j, k), Y(i, j, k), Z(i, j, k) + delta) - feval(f1, X(i, j, k), Y(i, j, k), Z(i, j, k) - delta)) / (2 * delta) - (feval(f3, X(i, j, k) + delta, Y(i, j, k), Z(i, j, k)) - feval(f3, X(i, j, k) - delta, Y(i, j, k), Z(i, j, k))) / (2 * delta);
                G_numerical(i, j, k, 3) = (feval(f2, X(i, j, k) + delta, Y(i, j, k), Z(i, j, k)) - feval(f2, X(i, j, k) - delta, Y(i, j, k), Z(i, j, k))) / (2 * delta) - (feval(f1, X(i, j, k), Y(i, j, k) + delta, Z(i, j, k)) - feval(f1, X(i, j, k), Y(i, j, k) - delta, Z(i, j, k))) / (2 * delta);
            end
        end
    end
    
    % Plotting the symbolic curl
    figure;
    quiver3(X, Y, Z, subs(g(1), v, {X, Y, Z}), subs(g(2), v, {X, Y, Z}), subs(g(3), v, {X, Y, Z}), 'Color', 'b');
    xlabel('X-axis');
    ylabel('Y-axis');
    zlabel('Z-axis');
    title('Symbolically Calculated Curl');
    hold off
    figure;
    % Plotting the numerical curl
    quiver3(X, Y, Z, G_numerical(:,:,:,1), G_numerical(:,:,:,2), G_numerical(:,:,:,3), 'Color', 'r');
    
    hold off;
    xlabel('X-axis');
    ylabel('Y-axis');
    zlabel('Z-axis');
    title('Numerically Calculated Curl');
end


        function solvecurl(app,x1,y1,z1)
            syms x y z real;
             i=sym('i') ;
             j=sym('j') ;
             k=sym('k') ;
             v=[x,y,z];
            p1=app.I_componentEditField.Value;
            p2=app.J_componentEditField.Value;
            p3=app.K_componentEditField.Value;
            f1=str2func(['@(x,y,z)' p1]);     
            f2=str2func(['@(x,y,z)' p2]);
            f3=str2func(['@(x,y,z)' p3]);
            f4=diff(f1,y);
            f5=diff(f1,z);
            f6=diff(f2,x);
            f7=diff(f2,z);
            f8=diff(f3,x);
            f9=diff(f3,y);
            g=[ f9-f7 ;  f5-f8 ; f6-f4];
             l1 = matlabFunction(g(1), 'Vars', [x, y, z]);
             l2 = matlabFunction(g(2), 'Vars', [x, y, z]);
             l3 = matlabFunction(g(3), 'Vars', [x, y, z]);
              
       %    for numeric 
               h = 1e-6;
               f1y = (f1(x1, y1 + h, z1) - f1(x1, y1, z1)) / h;
               f1z = (f1(x1, y1 , z1+h) - f1(x1, y1, z1)) / h;
               f2x=  (f2(x1+h, y1, z1 ) - f2(x1, y1, z1)) / h;
               f2z=(f2(x1, y1, z1 + h) - f2(x1, y1, z1)) / h;
               f3x = (f3(x1 + h, y1, z1) - f3(x1, y1, z1)) / h;
               f3y= (f3(x1 , y1+h, z1) - f3(x1, y1, z1)) / h;
               k111=f3y-f2z;
               k222= -f3x+f1z;
               k333=f2x-f1y;
           

             k1111 = l1(x1, y1, z1);
             k2222 = l2(x1, y1, z1);
             k3333 = l3(x1, y1, z1);
            l44=func2str(l1)
            indx=strfind(l44,'(');
            l4=l44(indx+7:end);
            l4=['(' l4 ')']
            l55=func2str(l2)
            indx=strfind(l55,'(');
            l5=l55(indx+7:end);
            l5=['(' l5 ')']
            l66=func2str(l3)
            indx=strfind(l66,'(');
            l6=l66(indx+7:end);
            l6=['(' l6 ')']
            l11=append(l4,'i','+',l5,'j','+',l6,'k');
            app.EditField2.Value=l11;
            w1=num2str(k111);
            w2=num2str(k222);
            w3=num2str(k333);
               
             w4=append(w1,"i")
             w5=append(w2,"j")
             w6=append(w3,"k")
             if k222>=0&k333>=0          
                 w7=append(w4,'+',w5,'+',w6)
             elseif k222>=0&k333<0
                 w6=append('(',w6,')')
                 w7=append(w4,'+',w5,'+',w6)
             elseif k222<0&k333>=0
                 w5=append('(',w5,')')
                 w7=append(w4,'+',w5,'+',w6)
              else 
                  w6=append('(',w6,')')
                  w5=append('(',w5,')')
                  w7=append(w4,'+',w5,'+',w6)
             end
             app.EditField3.Value=w7  

        end

        function solvediver(app, x1, y1, z1)
    syms x y z real;
    i = sym('i');
    j = sym('j');
    k = sym('k');

    v = [x, y, z];
    p1 = app.I_componentEditField.Value;
    p2 = app.J_componentEditField.Value;
    p3 = app.K_componentEditField.Value;
    f1 = str2func(['@(x,y,z)' p1]);
    f2 = str2func(['@(x,y,z)' p2]);
    f3 = str2func(['@(x,y,z)' p3]);

    % Symbolic differentiation
    g = [diff(f1, x); diff(f2, y); diff(f3, z)];
    l1 = matlabFunction(g(1), 'Vars', [x, y, z]);
    l2 = matlabFunction(g(2), 'Vars', [x, y, z]);
    l3 = matlabFunction(g(3), 'Vars', [x, y, z]);

    k111 = l1(x1, y1, z1);
    k222 = l2(x1, y1, z1);
    k333 = l3(x1, y1, z1);

    l44 = func2str(l1);
    indx = strfind(l44, ')');
    l444 = l44(indx+1:end);
    l4 = ['(' l444 ')'];

    l55 = func2str(l2);
    indx = strfind(l55, ')');
    l555 = l55(indx+1:end);
    l5 = ['(' l555 ')'];

    l66 = func2str(l3);
    indx = strfind(l66, '(');
    l666 = l66(indx+7:end);
    l6 = ['(' l666 ')'];

    l11 = append(l4,  '+', l5,  '+', l6);
    app.EditField_3.Value = l11;

    % Numerical differentiation for w7
    delta = 0.1;
    w1 = (feval(f1, x1 + delta, y1, z1) - feval(f1, x1 - delta, y1, z1)) / (2 * delta);
    w2 = (feval(f2, x1, y1 + delta, z1) - feval(f2, x1, y1 - delta, z1)) / (2 * delta);
    w3 = (feval(f3, x1, y1, z1 + delta) - feval(f3, x1, y1, z1 - delta)) / (2 * delta);

    w4 = append(num2str(w1), " ");
    w5 = append(num2str(w2), " ");
    w6 = append(num2str(w3), " ");

    if w2 >= 0 && w3 >= 0
        w7 = append(w4, '+', w5, '+', w6);
    elseif w2 >= 0 && w3 < 0
        w6 = append('(', w6, ')');
        w7 = append(w4, '+', w5, '+', w6);
    elseif w2 < 0 && w3 >= 0
        w5 = append('(', w5, ')');
        w7 = append(w4, '+', w5, '+', w6);
    else
        w6 = append('(', w6, ')');
        w5 = append('(', w5, ')');
        w7 = append(w4, '+', w5, '+', w6);
    end
    
    app.EditField_5.Value = k111 + k222 + k333;
    app.EditField_4.Value = w7;
end

function graphdiver(app)
    syms x y z real;

    v = [x, y, z];
    p1 = app.I_componentEditField.Value;
    p2 = app.J_componentEditField.Value;
    p3 = app.K_componentEditField.Value;

    f1 = str2func(['@(x,y,z)' p1]);
    f2 = str2func(['@(x,y,z)' p2]);
    f3 = str2func(['@(x,y,z)' p3]);

    % Symbolic divergence
    g = [diff(f1, x); diff(f2, y); diff(f3, z)];

    [X, Y, Z] = meshgrid(-1:0.1:1, -1:0.1:1, -1:0.1:1);
    G = subs(sum(g), v, {X, Y, Z});

    % Plotting symbolic divergence using scatter3
    figure;
    scatter3(X(:), Y(:), Z(:), 20, G(:), 'filled');
    title('Symbolic Divergence');
    xlabel('X-axis');
    ylabel('Y-axis');
    zlabel('Z-axis');
    colorbar;
    axis tight;
    grid on;

    % Numerical calculation of divergence
    delta = 0.1;
    [X_num, Y_num, Z_num] = meshgrid(-1:delta:1, -1:delta:1, -1:delta:1);
    G_num = zeros(size(X_num));

    for i = 1:size(X_num, 1)
        for j = 1:size(X_num, 2)
            for k = 1:size(X_num, 3)
                G_num(i, j, k) = (feval(f1, X_num(i, j, k) + delta, Y_num(i, j, k), Z_num(i, j, k)) - feval(f1, X_num(i, j, k) - delta, Y_num(i, j, k), Z_num(i, j, k))) / (2 * delta) + ...
                                (feval(f2, X_num(i, j, k), Y_num(i, j, k) + delta, Z_num(i, j, k)) - feval(f2, X_num(i, j, k), Y_num(i, j, k) - delta, Z_num(i, j, k))) / (2 * delta) + ...
                                (feval(f3, X_num(i, j, k), Y_num(i, j, k), Z_num(i, j, k) + delta) - feval(f3, X_num(i, j, k), Y_num(i, j, k), Z_num(i, j, k) - delta)) / (2 * delta);
            end
        end
    end

    % Plotting numerical divergence using scatter3
    figure;
    scatter3(X_num(:), Y_num(:), Z_num(:), 20, G_num(:), 'filled');
    title('Numerical Divergence (Finite Differences)');
    xlabel('X-axis');
    ylabel('Y-axis');
    zlabel('Z-axis');
    colorbar;
    axis tight;
    grid on;
end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FunctionEditField
        function FunctionEditFieldValueChanged(app, event)
            value = app.FunctionEditField.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            eq=app.FunctionEditField.Value;
            f=str2func(['@(x,y,z)' eq]);
            x=app.Slider1.Value;
            y=app.Slider2.Value;
            z=app.Slider3.Value;
            z1=f(x,y,z);
            app.ResultEditField.Value=z1;
           
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
           
            app.XEditField.Value = app.Slider1.Value;
        end

        % Value changing function: Slider1
        function Slider1ValueChanging(app, event)
            changingValue = event.Value;
            app.XEditField.Value=changingValue;
            x1=changingValue;
            y1=app.Slider2.Value;
            z1=app.Slider3.Value;
            solvegrad(app,x1,y1,z1)
            solvediver(app,x1,y1,z1)
            solvecurl(app,x1,y1,z1)
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            app.YEditField.Value=value;
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            app.YEditField.Value=changingValue;
            x1=app.Slider1.Value;
            y1=changingValue;
            z1=app.Slider3.Value;
            solvegrad(app,x1,y1,z1)
            solvediver(app,x1,y1,z1)
            solvecurl(app,x1,y1,z1)
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            value = app.Slider3.Value;
            app.ZEditField.Value=value;
        end

        % Value changing function: Slider3
        function Slider3ValueChanging(app, event)
            changingValue = event.Value;
            app.ZEditField.Value=changingValue;
            x1=app.Slider1.Value;
            y1=app.Slider2.Value;
            z1=changingValue;
            solvegrad(app,x1,y1,z1)
            solvediver(app,x1,y1,z1)
            solvecurl(app,x1,y1,z1)
        end

        % Button pushed function: GradientButton
        function GradientButtonPushed(app, event)
           
          x1=app.Slider1.Value;
          y1=app.Slider2.Value;
          z1=app.Slider3.Value;
          solvegrad(app,x1,y1,z1)
          graphgrad(app)

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: CurlButton
        function CurlButtonPushed(app, event)
          x1=app.Slider1.Value;
          y1=app.Slider2.Value;
          z1=app.Slider3.Value;
          graphcurl(app)
          solvecurl(app,x1,y1,z1)


        end

        % Button pushed function: DivergenceButton
        function DivergenceButtonPushed(app, event)
          x1=app.Slider1.Value;
          y1=app.Slider2.Value;
          z1=app.Slider3.Value;
          graphdiver(app);
          solvediver(app,x1,y1,z1)
        end

        % Value changing function: I_componentEditField
        function I_componentEditFieldValueChanging(app, event)
            changingValue = event.Value;
           
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            txt='First Take All input and Follow matrix Multiplication Rules'
            app.TextArea.Value = txt;
            
        end

        % Value changed function: ResultEditField
        function ResultEditFieldValueChanged(app, event)
            
            
            value = app.ResultEditField.Value;

            
        end

        % Value changing function: FunctionEditField
        function FunctionEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.Slider1Label = uilabel(app.UIFigure);
            app.Slider1Label.Text = 'Slider-1';
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.Limits = [-100 100];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider1.ValueChangingFcn = createCallbackFcn(app, @Slider1ValueChanging, true);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider-3';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-100 100];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.GradientPanel = uipanel(app.UIFigure);
            app.GradientPanel.Title = 'Gradient';
            app.GradientButton = uibutton(app.GradientPanel, 'push');
            app.GradientButton.ButtonPushedFcn = createCallbackFcn(app, @GradientButtonPushed, true);
            app.GradientButton.Text = 'Gradient';
            app.EditField_2 = uieditfield(app.GradientPanel, 'text');
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.FunctionEditFieldLabel = uilabel(app.GradientPanel);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.EditField = uieditfield(app.GradientPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.FunctionEditField = uieditfield(app.GradientPanel, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionEditFieldValueChanged, true);
            app.FunctionEditField.ValueChangingFcn = createCallbackFcn(app, @FunctionEditFieldValueChanging, true);
            app.ValueofFunctionatxyzPanel = uipanel(app.UIFigure);
            app.ValueofFunctionatxyzPanel.Title = 'Value of Function at (x,y,z)';
            app.CalculateButton = uibutton(app.ValueofFunctionatxyzPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultEditFieldLabel = uilabel(app.ValueofFunctionatxyzPanel);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.ValueofFunctionatxyzPanel, 'numeric');
            app.ResultEditField.ValueChangedFcn = createCallbackFcn(app, @ResultEditFieldValueChanged, true);
            app.AtFirstTakeAllInputValueAndFollowMatrixMultiplicationRulesLabel = uilabel(app.UIFigure);
            app.AtFirstTakeAllInputValueAndFollowMatrixMultiplicationRulesLabel.Interpreter = 'latex';
            app.AtFirstTakeAllInputValueAndFollowMatrixMultiplicationRulesLabel.Text = {'''At First Take All Input Value: Function, i_component, j_component and k_component'' '; 'Follow Matrix Multiplication Rules.'};
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider-2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-100 100];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.I_componentEditFieldLabel = uilabel(app.UIFigure);
            app.I_componentEditFieldLabel.Text = 'I_component';
            app.I_componentEditField = uieditfield(app.UIFigure, 'text');
            app.I_componentEditField.ValueChangingFcn = createCallbackFcn(app, @I_componentEditFieldValueChanging, true);
            app.J_componentEditFieldLabel = uilabel(app.UIFigure);
            app.J_componentEditFieldLabel.Text = 'J_component';
            app.J_componentEditField = uieditfield(app.UIFigure, 'text');
            app.K_componentEditFieldLabel = uilabel(app.UIFigure);
            app.K_componentEditFieldLabel.Text = 'K_component';
            app.K_componentEditField = uieditfield(app.UIFigure, 'text');
            app.CurlPanel = uipanel(app.UIFigure);
            app.CurlPanel.Title = 'Curl';
            app.CurlButton = uibutton(app.CurlPanel, 'push');
            app.CurlButton.ButtonPushedFcn = createCallbackFcn(app, @CurlButtonPushed, true);
            app.CurlButton.Text = 'Curl';
            app.EditField2 = uieditfield(app.CurlPanel, 'text');
            app.EditField3 = uieditfield(app.CurlPanel, 'text');
            app.DivergencePanel = uipanel(app.UIFigure);
            app.DivergencePanel.Title = 'Divergence';
            app.DivergenceButton = uibutton(app.DivergencePanel, 'push');
            app.DivergenceButton.ButtonPushedFcn = createCallbackFcn(app, @DivergenceButtonPushed, true);
            app.DivergenceButton.Text = 'Divergence';
            app.EditField_3 = uieditfield(app.DivergencePanel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.EditField_4 = uieditfield(app.DivergencePanel, 'text');
            app.EditField_5 = uieditfield(app.DivergencePanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAB_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end